classdef equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PauseButton              matlab.ui.control.Button
        ImportFileButton         matlab.ui.control.Button
        HzSlider_10              matlab.ui.control.Slider
        k10kHzSliderLabel        matlab.ui.control.Label
        k20kHzSliderLabel        matlab.ui.control.Label
        HzSlider_9               matlab.ui.control.Slider
        HzSlider_8               matlab.ui.control.Slider
        k5kHzSliderLabel         matlab.ui.control.Label
        HzSlider_7               matlab.ui.control.Slider
        k24kHzSliderLabel        matlab.ui.control.Label
        HzSlider_6               matlab.ui.control.Slider
        kHzSliderLabel           matlab.ui.control.Label
        HzSlider_5               matlab.ui.control.Slider
        HzLabel_5                matlab.ui.control.Label
        HzSlider_4               matlab.ui.control.Slider
        HzLabel_4                matlab.ui.control.Label
        HzSlider_3               matlab.ui.control.Slider
        HzLabel_3                matlab.ui.control.Label
        HzSlider_2               matlab.ui.control.Slider
        HzLabel_2                matlab.ui.control.Label
        HzSlider_1               matlab.ui.control.Slider
        HzLabel                  matlab.ui.control.Label
        EqualizerLabel           matlab.ui.control.Label
        MainVolumeSlider_3       matlab.ui.control.Slider
        MainVolumeSlider_3Label  matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Equalizer
    properties (Access = private)
        % For importing audio files from device
        filename;
        path;

        % For controlling audio
        orig;
        audio;
        volume = 1;

        % Sampling rate and Nyquist frequency
        Fs;
        Fn;
        
        % Audio player
        player;
        paused = 0;

        % Frequency sliders
        f1, f2, f3, f4, f5, f6, f7, f8, f9, f10, f11;
        a1, a2, a3, a4, a5, a6, a7, a8, a9, a10;
        y1, y2, y3, y4, y5, y6, y7, y8, y9, y10;

        N = 1280;
    end
    
    methods (Access = private)
        function sum = AddSound(app)
            % Disable all interface components
            toggleAll(app, 'Off');

            % "Save" audio playback state and stop audio player, if one is active
            resume_at = 1;
            if ~isempty(app.player)
                resume_at = app.player.CurrentSample;
                stop(app.player);
            end

            % Reconstruct audio signal from component frequencies
            sum = app.y1 + app.y2 + app.y3 + app.y4 + app.y5 + app.y6 + app.y7 + app.y8 + app.y9 + app.y10;
            
            % Create a new audio player that resumes or starts from the beginning
            app.player = audioplayer(app.volume.*sum, app.Fs);
            play(app.player,resume_at);

            % Enable all interface components
            toggleAll(app, 'On');
        end

        function toggleAll(app, flag)
            components = [app.PlayButton, app.StopButton, app.HzSlider_1, ...
                app.HzSlider_2, app.HzSlider_3, app.HzSlider_4, app.HzSlider_5, ...
                app.HzSlider_6, app.HzSlider_7, app.HzSlider_8, app.HzSlider_9, ...
                app.HzSlider_10, app.ImportFileButton, app.PauseButton, app.MainVolumeSlider_3];
            set(components, 'Enable', flag);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            plot(app.UIAxes_2, app.audio);
            title(app.UIAxes_2, 'Equalized Audio');
            if app.paused == 0
                app.player = audioplayer(app.volume.*app.audio, app.Fs);
                play(app.player);
            else
                resume(app.player);
                app.paused = 0;
            end
        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            [app.filename,app.path]=uigetfile();

            if app.filename == 0
                return
            end

            toggleAll(app, 'Off');
            app.ImportFileButton.Text = "Importing...";

            [app.orig, app.Fs] = audioread(app.filename);
            app.Fn = app.Fs/2;

            app.f1 = 20/app.Fn;
            app.f2 = 40/app.Fn;
            app.f3 = 80/app.Fn;
            app.f4 = 160/app.Fn;
            app.f5 = 300/app.Fn;
            app.f6 = 600/app.Fn;
            app.f7 = 1200/app.Fn;
            app.f8 = 2400/app.Fn;
            app.f9 = 5000/app.Fn;
            app.f10 = 10000/app.Fn;
            app.f11 = 20000/app.Fn;

            app.a1 = fir1(app.N, [app.f1 app.f2], 'bandpass');
            app.a2 = fir1(app.N, [app.f2 app.f3], 'bandpass');
            app.a3 = fir1(app.N, [app.f3 app.f4], 'bandpass');
            app.a4 = fir1(app.N, [app.f4 app.f5], 'bandpass');
            app.a5 = fir1(app.N, [app.f5 app.f6], 'bandpass');
            app.a6 = fir1(app.N, [app.f6 app.f7], 'bandpass');
            app.a7 = fir1(app.N, [app.f7 app.f8], 'bandpass');
            app.a8 = fir1(app.N, [app.f8 app.f9], 'bandpass');
            app.a9 = fir1(app.N, [app.f9 app.f10], 'bandpass');
            app.a10 = fir1(app.N, [app.f10 app.f11], 'bandpass');
            
            app.y1 = filter(app.a1, 1, app.orig);
            app.y2 = filter(app.a2, 1, app.orig);
            app.y3 = filter(app.a3, 1, app.orig);
            app.y4 = filter(app.a4, 1, app.orig);
            app.y5 = filter(app.a5, 1, app.orig);
            app.y6 = filter(app.a6, 1, app.orig);
            app.y7 = filter(app.a7, 1, app.orig);
            app.y8 = filter(app.a8, 1, app.orig);
            app.y9 = filter(app.a9, 1, app.orig);
            app.y10 = filter(app.a10, 1, app.orig);
            
            app.audio = AddSound(app);

            plot(app.UIAxes, app.orig);
            title(app.UIAxes, app.filename);

            app.ImportFileButton.Text = "Import File";
            toggleAll(app, 'On');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player);
            app.paused = 0;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(app.player);
            app.paused = 1;
        end

        % Value changed function: HzSlider_1
        function HzSlider_1ValueChanged(app, event)
            val = app.HzSlider_1.Value;
            if(val>=0)
                app.y1 = (val/2+1).*filter(app.a1, 1, app.orig);
            else
                app.y1= (-1/(val/2-1)).*filter(app.a1, 1, app.orig);
            end
            app.audio = AddSound(app);
            plot(app.UIAxes_2, app.audio);
            title(app.UIAxes_2, 'Equalized Audio');
        end

        % Value changed function: HzSlider_2
        function HzSlider_2ValueChanged(app, event)
            val = app.HzSlider_2.Value;
            if(val>=0)
                app.y2 = (val/2+1).*filter(app.a2, 1, app.orig);
            else
                app.y2= (-1/(val/2-1)).*filter(app.a2, 1, app.orig);
            end            
            app.audio = AddSound(app);
            plot(app.UIAxes_2, app.audio);
            title(app.UIAxes_2, 'Equalized Audio');
        end

        % Value changed function: HzSlider_3
        function HzSlider_3ValueChanged(app, event)
            val = app.HzSlider_3.Value;
            if(val>=0)
                app.y3 = (val/2+1).*filter(app.a3, 1, app.orig);
            else
                app.y3= (-1/(val/2-1)).*filter(app.a3, 1, app.orig);
            end                        
            app.audio = AddSound(app);
            plot(app.UIAxes_2, app.audio);
            title(app.UIAxes_2, 'Equalized Audio');
        end

        % Value changed function: HzSlider_4
        function HzSlider_4ValueChanged(app, event)
            val = app.HzSlider_4.Value;
            if(val>=0)
                app.y4 = (val/2+1).*filter(app.a4, 1, app.orig);
            else
                app.y4= (-1/(val/2-1)).*filter(app.a4, 1, app.orig);
            end                        
            app.audio = AddSound(app);
            plot(app.UIAxes_2, app.audio);
            title(app.UIAxes_2, 'Equalized Audio');
        end

        % Value changed function: HzSlider_5
        function HzSlider_5ValueChanged(app, event)
            val = app.HzSlider_5.Value;
            if(val>=0)
                app.y5 = (val/2+1).*filter(app.a5, 1, app.orig);
            else
                app.y5 = (-1/(val/2-1)).*filter(app.a5, 1, app.orig);
            end
            app.audio = AddSound(app);
            plot(app.UIAxes_2, app.audio);
            title(app.UIAxes_2, 'Equalized Audio');
        end

        % Value changed function: HzSlider_6
        function HzSlider_6ValueChanged(app, event)
            val = app.HzSlider_6.Value;
            if(val>=0)
                app.y6 = (val/2+1).*filter(app.a6, 1, app.orig);
            else
                app.y6= (-1/(val/2-1)).*filter(app.a6, 1, app.orig);
            end
            app.audio = AddSound(app);
            plot(app.UIAxes_2, app.audio);
            title(app.UIAxes_2, 'Equalized Audio');
        end

        % Value changed function: HzSlider_7
        function HzSlider_7ValueChanged(app, event)
            val = app.HzSlider_7.Value;
            if(val>=0)
                app.y7 = (val/2+1).*filter(app.a7, 1, app.orig);
            else
                app.y7= (-1/(val/2-1)).*filter(app.a7, 1, app.orig);
            end            
            app.audio = AddSound(app);
            plot(app.UIAxes_2, app.audio);
            title(app.UIAxes_2, 'Equalized Audio');
        end

        % Value changed function: HzSlider_8
        function HzSlider_8ValueChanged(app, event)
            val = app.HzSlider_8.Value;
            if(val>=0)
                app.y8 = (val/2+1).*filter(app.a8, 1, app.orig);
            else
                app.y8= (-1/(val/2-1)).*filter(app.a8, 1, app.orig);
            end
            app.audio = AddSound(app);
            plot(app.UIAxes_2, app.audio);
            title(app.UIAxes_2, 'Equalized Audio');
        end

        % Value changed function: HzSlider_9
        function HzSlider_9ValueChanged(app, event)
            val = app.HzSlider_9.Value;
            if(val>=0)
                app.y9 = (val/2+1).*filter(app.a9, 1, app.orig);
            else
                app.y9= (-1/(val/2-1)).*filter(app.a9, 1, app.orig);
            end            
            app.audio = AddSound(app);
            plot(app.UIAxes_2, app.audio);
            title(app.UIAxes_2, 'Equalized Audio');
        end

        % Value changed function: HzSlider_10
        function HzSlider_10ValueChanged(app, event)
            val = app.HzSlider_10.Value;
            app.y10 = (val+1).*filter(app.a10, 1, app.orig);
            app.audio = AddSound(app);
            plot(app.UIAxes_2, app.audio);
            title(app.UIAxes_2, 'Equalized Audio');
        end

        % Value changing function: MainVolumeSlider_3
        function MainVolumeSlider_3ValueChanging(app, event)
            % app.volume = event.Value;
            
        end

        % Value changed function: MainVolumeSlider_3
        function MainVolumeSlider_3ValueChanged(app, event)
            app.volume = app.MainVolumeSlider_3.Value;
            app.audio = AddSound(app);
            plot(app.UIAxes_2, app.volume .* app.audio);
            title(app.UIAxes_2, 'Equalized Audio');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Audio')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Modified Audio')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.MainVolumeSlider_3Label = uilabel(app.UIFigure);
            app.MainVolumeSlider_3Label.Text = 'Main Volume';
            app.MainVolumeSlider_3 = uislider(app.UIFigure);
            app.MainVolumeSlider_3.Limits = [0 2];
            app.MainVolumeSlider_3.ValueChangedFcn = createCallbackFcn(app, @MainVolumeSlider_3ValueChanged, true);
            app.MainVolumeSlider_3.ValueChangingFcn = createCallbackFcn(app, @MainVolumeSlider_3ValueChanging, true);
            app.MainVolumeSlider_3.Value = 1;
            app.EqualizerLabel = uilabel(app.UIFigure);
            app.EqualizerLabel.Text = 'Equalizer';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '20-40Hz';
            app.HzSlider_1 = uislider(app.UIFigure);
            app.HzSlider_1.Limits = [-12 12];
            app.HzSlider_1.ValueChangedFcn = createCallbackFcn(app, @HzSlider_1ValueChanged, true);
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = '40-80Hz';
            app.HzSlider_2 = uislider(app.UIFigure);
            app.HzSlider_2.Limits = [-12 12];
            app.HzSlider_2.ValueChangedFcn = createCallbackFcn(app, @HzSlider_2ValueChanged, true);
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = '80-160Hz';
            app.HzSlider_3 = uislider(app.UIFigure);
            app.HzSlider_3.Limits = [-12 12];
            app.HzSlider_3.ValueChangedFcn = createCallbackFcn(app, @HzSlider_3ValueChanged, true);
            app.HzLabel_4 = uilabel(app.UIFigure);
            app.HzLabel_4.Text = '160-300Hz';
            app.HzSlider_4 = uislider(app.UIFigure);
            app.HzSlider_4.Limits = [-12 12];
            app.HzSlider_4.ValueChangedFcn = createCallbackFcn(app, @HzSlider_4ValueChanged, true);
            app.HzLabel_5 = uilabel(app.UIFigure);
            app.HzLabel_5.Text = '300-600Hz';
            app.HzSlider_5 = uislider(app.UIFigure);
            app.HzSlider_5.Limits = [-12 12];
            app.HzSlider_5.ValueChangedFcn = createCallbackFcn(app, @HzSlider_5ValueChanged, true);
            app.kHzSliderLabel = uilabel(app.UIFigure);
            app.kHzSliderLabel.Text = '600-1.2kHz';
            app.HzSlider_6 = uislider(app.UIFigure);
            app.HzSlider_6.Limits = [-12 12];
            app.HzSlider_6.ValueChangedFcn = createCallbackFcn(app, @HzSlider_6ValueChanged, true);
            app.k24kHzSliderLabel = uilabel(app.UIFigure);
            app.k24kHzSliderLabel.Text = '1.2k-2.4kHz';
            app.HzSlider_7 = uislider(app.UIFigure);
            app.HzSlider_7.Limits = [-12 12];
            app.HzSlider_7.ValueChangedFcn = createCallbackFcn(app, @HzSlider_7ValueChanged, true);
            app.k5kHzSliderLabel = uilabel(app.UIFigure);
            app.k5kHzSliderLabel.Text = '2.4k-5kHz';
            app.HzSlider_8 = uislider(app.UIFigure);
            app.HzSlider_8.Limits = [-12 12];
            app.HzSlider_8.ValueChangedFcn = createCallbackFcn(app, @HzSlider_8ValueChanged, true);
            app.HzSlider_9 = uislider(app.UIFigure);
            app.HzSlider_9.Limits = [-12 12];
            app.HzSlider_9.ValueChangedFcn = createCallbackFcn(app, @HzSlider_9ValueChanged, true);
            app.k20kHzSliderLabel = uilabel(app.UIFigure);
            app.k20kHzSliderLabel.Text = '10k-20kHz';
            app.k10kHzSliderLabel = uilabel(app.UIFigure);
            app.k10kHzSliderLabel.Text = '5k-10kHz';
            app.HzSlider_10 = uislider(app.UIFigure);
            app.HzSlider_10.Limits = [-12 12];
            app.HzSlider_10.ValueChangedFcn = createCallbackFcn(app, @HzSlider_10ValueChanged, true);
            app.ImportFileButton = uibutton(app.UIFigure, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end